classdef GUI_ControlRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        IntroduceTab            matlab.ui.container.Tab
        btn_stop                matlab.ui.control.Button
        btn_start               matlab.ui.control.Button
        KinematicsTab           matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        GridLayout8             matlab.ui.container.GridLayout
        Btn_Calculate           matlab.ui.control.Button
        Btn_Check               matlab.ui.control.Button
        GridLayout7             matlab.ui.container.GridLayout
        GridLayout9_6           matlab.ui.container.GridLayout
        Label_T3_2              matlab.ui.control.Label
        Label_IK_T3             matlab.ui.control.Label
        GridLayout9_5           matlab.ui.container.GridLayout
        Label_T2_2              matlab.ui.control.Label
        Label_IK_T2             matlab.ui.control.Label
        GridLayout9_4           matlab.ui.container.GridLayout
        Label_T1_2              matlab.ui.control.Label
        Label_IK_T1             matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6_3           matlab.ui.container.GridLayout
        Pz_IK_EditField         matlab.ui.control.EditField
        PzEditFieldLabel        matlab.ui.control.Label
        GridLayout6_2           matlab.ui.container.GridLayout
        Py_IK_EditField         matlab.ui.control.EditField
        PyEditFieldLabel        matlab.ui.control.Label
        GridLayout6             matlab.ui.container.GridLayout
        Px_IK_EditField         matlab.ui.control.EditField
        PxEditFieldLabel        matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout9_3           matlab.ui.container.GridLayout
        Label_Pz                matlab.ui.control.Label
        Label_FK_Pz             matlab.ui.control.Label
        GridLayout9_2           matlab.ui.container.GridLayout
        Label_Py                matlab.ui.control.Label
        Label_FK_Py             matlab.ui.control.Label
        GridLayout9             matlab.ui.container.GridLayout
        Label_Px                matlab.ui.control.Label
        Label_FK_Px             matlab.ui.control.Label
        GridLayout2_3           matlab.ui.container.GridLayout
        Label_FK_T3             matlab.ui.control.Label
        Theta3Slider            matlab.ui.control.Slider
        Theta3SliderLabel       matlab.ui.control.Label
        GridLayout2_2           matlab.ui.container.GridLayout
        Label_FK_T2             matlab.ui.control.Label
        Theta2Slider            matlab.ui.control.Slider
        Theta2SliderLabel       matlab.ui.control.Label
        ForwardKinematicsLabel  matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        Label_FK_T1             matlab.ui.control.Label
        Theta1Slider            matlab.ui.control.Slider
        Theta1SliderLabel       matlab.ui.control.Label
        TrajectoryPlanningTab   matlab.ui.container.Tab
        GridLayout10            matlab.ui.container.GridLayout
        GridLayout14            matlab.ui.container.GridLayout
        GridLayout14_3          matlab.ui.container.GridLayout
        nEditField              matlab.ui.control.EditField
        nEditFieldLabel         matlab.ui.control.Label
        GridLayout14_2          matlab.ui.container.GridLayout
        tfEditField             matlab.ui.control.EditField
        tfEditFieldLabel        matlab.ui.control.Label
        Btn_ClearResult         matlab.ui.control.Button
        GridLayout12            matlab.ui.container.GridLayout
        Btn_2P                  matlab.ui.control.Button
        Btn_3P                  matlab.ui.control.Button
        Label_ModeCheck         matlab.ui.control.Label
        GridLayout11_3          matlab.ui.container.GridLayout
        Label_PointC            matlab.ui.control.Label
        EditField_PCy           matlab.ui.control.EditField
        EditField_PCx           matlab.ui.control.EditField
        GridLayout11_2          matlab.ui.container.GridLayout
        Label_PonitB            matlab.ui.control.Label
        EditField_PBy           matlab.ui.control.EditField
        EditField_PBx           matlab.ui.control.EditField
        Label_SettingPoint      matlab.ui.control.Label
        GridLayout11            matlab.ui.container.GridLayout
        Label_PointA            matlab.ui.control.Label
        EditField_PAy           matlab.ui.control.EditField
        EditField_PAx           matlab.ui.control.EditField
        UIAxes_TP               matlab.ui.control.UIAxes
        ObserveTab              matlab.ui.container.Tab
        GridLayout13            matlab.ui.container.GridLayout
        Btn_PlotY               matlab.ui.control.Button
        Btn_PlotX               matlab.ui.control.Button
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t1 % Description
        t2
        t3
        
        Px_IK
        Py_IK
        Pz_IK

        ss_GUI
        IsConnected = false;
    end

    
    methods (Access = private)
        
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ModelName = 'qdqd_robot';
            
            mode = 0;
            t1_mode1 = 0;
            t2_mode1 = 0;
            t3_mode1 = 0;

            t1_mode2 = 0;
            t2_mode2 = 0;
            t3_mode2 = 0;
            
            Pax = 0;
            Pay = 0;
            Pbx = 0;
            Pby = 0;
            Pcx = 0;
            Pcy = 0;
            tf = 0;
            n = 0;

            app.btn_start.Enable = 'on';
            app.btn_stop.Enable = 'off';

            %Kinemematics side
            app.Theta1Slider.Enable = 'off';
            app.Theta2Slider.Enable = 'off';
            app.Theta3Slider.Enable = 'off';
            app.Px_IK_EditField.Enable = 'off';
            app.Py_IK_EditField.Enable = 'off';
            app.Pz_IK_EditField.Enable = 'off';
            app.Btn_Check.Enable = 'off';
            app.Btn_Calculate.Enable = 'off';

            %TrajectoryPlanning side
            app.EditField_PAx.Enable ="off";
            app.EditField_PAy.Enable ="off";
            app.EditField_PBx.Enable ="off";
            app.EditField_PBy.Enable ="off";
            app.EditField_PCx.Enable ="off";
            app.EditField_PCy.Enable ="off";
            app.tfEditField.Enable = "off";
            app.nEditField.Enable = "off";            
            app.Btn_2P.Enable ="off";
            app.Btn_3P.Enable ="off";
            app.Btn_ClearResult.Enable ="off";

            %observe side
            app.Btn_PlotX.Enable ="off";
            app.Btn_PlotY.Enable ="off";

            sim(ModelName);
            %set default
            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode1'],'Value',num2str(t1_mode1));
            set_param([ModelName '/t2_mode1'],'Value',num2str(t2_mode1));
            set_param([ModelName '/t3_mode1'],'Value',num2str(t3_mode1));
            set_param([ModelName '/t1_mode2'],'Value',num2str(t1_mode2));
            set_param([ModelName '/t2_mode2'],'Value',num2str(t2_mode2));
            set_param([ModelName '/t3_mode2'],'Value',num2str(t3_mode2));
            set_param([ModelName '/3diem/Pax_mode4'],'Value',num2str(Pax));
            set_param([ModelName '/3diem/Pay_mode4'],'Value',num2str(Pay));
            set_param([ModelName '/3diem/Pbx_mode4'],'Value',num2str(Pbx));
            set_param([ModelName '/3diem/Pby_mode4'],'Value',num2str(Pby));
            set_param([ModelName '/3diem/Pcx_mode4'],'Value',num2str(Pcx));
            set_param([ModelName '/3diem/Pcy_mode4'],'Value',num2str(Pcy));
            set_param([ModelName '/3diem/tf_mode4'],'Value',num2str(tf));
            set_param([ModelName '/3diem/n_mode4'],'Value',num2str(n));
 
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
                ModelName = 'qdqd_robot';
                 
                mode = 1;
                l1 = 100;
                l2 = 250;
                l3 = 250;
                l4 = 150;

                d1 = 30;
                d2 = 30;
                d3 = 30;
                l0 = 150;
                theta123 = 0;
    
                app.t1 = app.Theta1Slider.Value;
                app.t2 = app.Theta2Slider.Value;
                app.t3 = app.Theta3Slider.Value;
                app.Label_FK_T1.Text = num2str(app.t1) ;
    
                Px = l1 + l2*cosd(app.t1) + l3*cosd(app.t1 + app.t2) + l4*cosd(theta123);
                Py = l2*sind(app.t1) + l3*sind(app.t1 + app.t2) + l4*sind(theta123);
                Pz = l0 + d1 + d2 + d3;
                
                app.Label_FK_Px.Text = num2str(round(Px, 2));
                app.Label_FK_Py.Text = num2str(round(Py, 2));
                app.Label_FK_Pz.Text = num2str(Pz);
    
                set_param([ModelName '/Mode'],'Value',num2str(mode));
                set_param([ModelName '/t1_mode1'],'Value',num2str(app.t1));
                set_param([ModelName '/t2_mode1'],'Value',num2str(app.t2));
                set_param([ModelName '/t3_mode1'],'Value',num2str(app.t3));                                
                            

        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            ModelName = 'qdqd_robot';

            mode = 1;
            l1 = 100;
            l2 = 250;
            l3 = 250;
            l4 = 150;
            d1 = 30;
            d2 = 30;
            d3 = 30;
            l0 = 150;
            theta123 = 0;
        
            Px = l1 + l2*cosd(app.t1) + l3*cosd(app.t1 + app.t2) + l4*cosd(theta123);
            Py = l2*sind(app.t1) + l3*sind(app.t1 + app.t2) + l4*sind(theta123);
            Pz = l0 + d1 + d2 + d3;

            app.t1 = app.Theta1Slider.Value;
            app.t2 = app.Theta2Slider.Value;
            app.t3 = app.Theta3Slider.Value;
            app.Label_FK_T2.Text = num2str(app.t2) ;
            
            app.Label_FK_Px.Text = num2str(round(Px, 2));
            app.Label_FK_Py.Text = num2str(round(Py, 2));
            app.Label_FK_Pz.Text = num2str(Pz);

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode1'],'Value',num2str(app.t1));
            set_param([ModelName '/t2_mode1'],'Value',num2str(app.t2));
            set_param([ModelName '/t3_mode1'],'Value',num2str(app.t3));
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            ModelName = 'qdqd_robot';
   
            mode = 1;
            l1 = 100;
            l2 = 250;
            l3 = 250;
            l4 = 150;
            d1 = 30;
            d2 = 30;
            d3 = 30;
            l0 = 150;
            theta123 = 0;
        
            Px = l1 + l2*cosd(app.t1) + l3*cosd(app.t1 + app.t2) + l4*cosd(theta123);
            Py = l2*sind(app.t1) + l3*sind(app.t1 + app.t2) + l4*sind(theta123);
            Pz = l0 + d1 + d2 + d3;

            app.t1 = app.Theta1Slider.Value;
            app.t2 = app.Theta2Slider.Value;
            app.t3 = app.Theta3Slider.Value;
            app.Label_FK_T3.Text = num2str(app.t3) ;
            
            app.Label_FK_Px.Text = num2str(round(Px, 2));
            app.Label_FK_Py.Text = num2str(round(Py, 2));
            app.Label_FK_Pz.Text = num2str(Pz);

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode1'],'Value',num2str(app.t1));
            set_param([ModelName '/t2_mode1'],'Value',num2str(app.t2));
            set_param([ModelName '/t3_mode1'],'Value',num2str(app.t3));          
        end

        % Value changed function: Px_IK_EditField
        function Px_IK_EditFieldValueChanged(app, event)
            ModelName = 'qdqd_robot';

            % Get values from Edit Fields
            app.Px_IK = str2double(app.Px_IK_EditField.Value);

            app.Py_IK = str2double(app.Py_IK_EditField.Value);

            app.Pz_IK = str2double(app.Pz_IK_EditField.Value);

            l1 = 100;
            l2 = 250;
            l3 = 250;
            l4 = 150;
            theta123 = 0;

            %tinh theta2
            nx = app.Px_IK  - l1 -l4*cosd(theta123);
            ny = app.Py_IK - l4*sind(theta123); 

            s2 = sqrt(complex(1 - ((nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3))^2));
            s2 = real(s2);
            c2 = (nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3);
            theta2 = atan2d (s2, c2);
        
            %tinh theta1
            A1 = ny * (l2 + l3 * cosd(theta2)) - l3*sind(theta2)*nx;
            A2 = (l2 + l3*cosd(theta2))^2 + (l3*sind(theta2))^2;
            A = A1/A2;
        
            B = (nx + l3*sind(theta2)*A) / (l2 + l3*cosd(theta2));
            theta1 = atan2d(A, B);
            %tinh theta3
            theta3 = theta123 - (theta2 + theta1);

            % Update Simulink block parameters
            mode = 2;

            % Update displayed theta values
            app.Label_IK_T1.Text = num2str(round(theta1, 2));
            app.Label_IK_T2.Text = num2str(round(theta2, 2));
            app.Label_IK_T3.Text = num2str(round(theta3, 2));

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode2'],'Value',num2str(theta1));
            set_param([ModelName '/t2_mode2'],'Value',num2str(theta2));
            set_param([ModelName '/t3_mode2'],'Value',num2str(theta3));         
        end

        % Value changed function: Py_IK_EditField
        function Py_IK_EditFieldValueChanged(app, event)
            ModelName = 'qdqd_robot';

            % Get values from Edit Fields
            app.Px_IK = str2double(app.Px_IK_EditField.Value);

            app.Py_IK = str2double(app.Py_IK_EditField.Value);

            app.Pz_IK = str2double(app.Pz_IK_EditField.Value);

            l1 = 100;
            l2 = 250;
            l3 = 250;
            l4 = 150;
            theta123 = 0;

            %tinh theta2
            nx = app.Px_IK  - l1 -l4*cosd(theta123);
            ny = app.Py_IK - l4*sind(theta123); 

            s2 = sqrt(complex(1 - ((nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3))^2));
            s2 = real(s2);
            c2 = (nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3);
            theta2 = atan2d (s2, c2);

            %tinh theta1
            A1 = ny * (l2 + l3 * cosd(theta2)) - l3*sind(theta2)*nx;
            A2 = (l2 + l3*cosd(theta2))^2 + (l3*sind(theta2))^2;
            A = A1/A2;
        
            B = (nx + l3*sind(theta2)*A) / (l2 + l3*cosd(theta2));
            theta1 = atan2d(A, B);
            %tinh theta3
            theta3 = theta123 - (theta2 + theta1);

            % Update Simulink block parameters
            mode = 2;

            % Update displayed theta values
            app.Label_IK_T1.Text = num2str(round(theta1, 2));
            app.Label_IK_T2.Text = num2str(round(theta2, 2));
            app.Label_IK_T3.Text = num2str(round(theta3, 2));

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode2'],'Value',num2str(theta1));
            set_param([ModelName '/t2_mode2'],'Value',num2str(theta2));
            set_param([ModelName '/t3_mode2'],'Value',num2str(theta3));             
        end

        % Value changed function: Pz_IK_EditField
        function Pz_IK_EditFieldValueChanged(app, event)
            ModelName = 'qdqd_robot';

            % Get values from Edit Fields
            app.Px_IK = str2double(app.Px_IK_EditField.Value);

            app.Py_IK = str2double(app.Py_IK_EditField.Value);

            app.Pz_IK = str2double(app.Pz_IK_EditField.Value);

            l1 = 100;
            l2 = 250;
            l3 = 250;
            l4 = 150;
            theta123 = 0;

            %tinh theta2
            nx = app.Px_IK  - l1 -l4*cosd(theta123);
            ny = app.Py_IK - l4*sind(theta123); 

            s2 = sqrt(complex(1 - ((nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3))^2));
            s2 = real(s2);
            c2 = (nx^2 + ny^2 - l2^2 - l3^2) / (2 * l2 * l3);
            theta2 = atan2d (s2, c2);   

            %tinh theta1
            A1 = ny * (l2 + l3 * cosd(theta2)) - l3*sind(theta2)*nx;
            A2 = (l2 + l3*cosd(theta2))^2 + (l3*sind(theta2))^2;
            A = A1/A2;
        
            B = (nx + l3*sind(theta2)*A) / (l2 + l3*cosd(theta2));
            theta1 = atan2d(A, B);
            %tinh theta3
            theta3 = theta123 - (theta2 + theta1);

            % Update Simulink block parameters
            mode = 2;

            % Update displayed theta values
            app.Label_IK_T1.Text = num2str(round(theta1, 2));
            app.Label_IK_T2.Text = num2str(round(theta2, 2));
            app.Label_IK_T3.Text = num2str(round(theta3, 2));

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/t1_mode2'],'Value',num2str(theta1));
            set_param([ModelName '/t2_mode2'],'Value',num2str(theta2));
            set_param([ModelName '/t3_mode2'],'Value',num2str(theta3));              
        end

        % Button pushed function: btn_start
        function btn_startButtonPushed(app, event)

            % Bật tắt nút theo điều kiện
            app.btn_start.Enable = 'off';
            app.btn_stop.Enable = 'on';   

            %Kinemematics side
            app.Theta1Slider.Enable = 'on';
            app.Theta2Slider.Enable = 'on';
            app.Theta3Slider.Enable = 'on';
            app.Px_IK_EditField.Enable = 'on';
            app.Py_IK_EditField.Enable = 'on';
            app.Pz_IK_EditField.Enable = 'on';
            app.Btn_Check.Enable = 'on';
            app.Btn_Calculate.Enable = 'on';

            %TrajectoryPlanning side
            app.EditField_PAx.Enable ="on";
            app.EditField_PAy.Enable ="on";
            app.EditField_PBx.Enable ="on";
            app.EditField_PBy.Enable ="on";
            app.EditField_PCx.Enable ="on";
            app.EditField_PCy.Enable ="on";
            app.tfEditField.Enable = "on";
            app.nEditField.Enable = "on";            
            app.Btn_2P.Enable ="on";
            app.Btn_3P.Enable ="on";
            app.Btn_ClearResult.Enable ="on";

            %observe side
            app.Btn_PlotX.Enable ="on";
            app.Btn_PlotY.Enable ="on";
        end

        % Button pushed function: btn_stop
        function btn_stopButtonPushed(app, event)
            app.btn_start.Enable = 'on';
            app.btn_stop.Enable = 'off';

            %Kinemematics side
            app.Theta1Slider.Enable = 'off';
            app.Theta2Slider.Enable = 'off';
            app.Theta3Slider.Enable = 'off';
            app.Px_IK_EditField.Enable = 'off';
            app.Py_IK_EditField.Enable = 'off';
            app.Pz_IK_EditField.Enable = 'off';
            app.Btn_Check.Enable = 'off';
            app.Btn_Calculate.Enable = 'off';

            %TrajectoryPlanning side
            app.EditField_PAx.Enable ="off";
            app.EditField_PAy.Enable ="off";
            app.EditField_PBx.Enable ="off";
            app.EditField_PBy.Enable ="off";
            app.EditField_PCx.Enable ="off";
            app.EditField_PCy.Enable ="off";
            app.tfEditField.Enable = "off";
            app.nEditField.Enable = "off";
            app.Btn_2P.Enable ="off";
            app.Btn_3P.Enable ="off";
            app.Btn_ClearResult.Enable ="off";

            %observe side
            app.Btn_PlotX.Enable ="off";
            app.Btn_PlotY.Enable ="off";
        end

        % Button pushed function: Btn_Check
        function Btn_CheckButtonPushed(app, event)
            ModelName = 'qdqd_robot';
            sim(ModelName);   
        end

        % Button pushed function: Btn_2P
        function Btn_2PButtonPushed(app, event)
            ModelName = 'qdqd_robot';
            mode = 3;

            Pax = num2str(app.EditField_PAx.Value);
            Pay = num2str(app.EditField_PAy.Value);

            Pbx = num2str(app.EditField_PBx.Value);
            Pby = num2str(app.EditField_PBy.Value);

            tf = num2str(app.tfEditField.Value);
            n = num2str(app.nEditField.Value); 

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/2diem/Pax_mode3'],'Value',num2str(Pax));
            set_param([ModelName '/2diem/Pay_mode3'],'Value',num2str(Pay));
            set_param([ModelName '/2diem/Pbx_mode3'],'Value',num2str(Pbx)); 
            set_param([ModelName '/2diem/Pby_mode3'],'Value',num2str(Pby)); 
            set_param([ModelName '/2diem/tf_mode3'],'Value',num2str(tf)); 
            set_param([ModelName '/2diem/n_mode3'],'Value',num2str(n)); 
            
            sim(ModelName);
            simout = sim(ModelName);
            plot(app.UIAxes_TP, simout.x.Data, simout.y.Data)
        end

        % Button pushed function: Btn_3P
        function Btn_3PButtonPushed(app, event)
            ModelName = 'qdqd_robot';
            mode = 4;

            Pax = num2str(app.EditField_PAx.Value);
            Pay = num2str(app.EditField_PAy.Value);

            Pbx = num2str(app.EditField_PBx.Value);
            Pby = num2str(app.EditField_PBy.Value);

            Pcx = num2str(app.EditField_PCx.Value);
            Pcy = num2str(app.EditField_PCy.Value);

            tf = num2str(app.tfEditField.Value);
            n = num2str(app.nEditField.Value);

            set_param([ModelName '/Mode'],'Value',num2str(mode));
            set_param([ModelName '/3diem/Pax_mode4'],'Value',num2str(Pax));
            set_param([ModelName '/3diem/Pay_mode4'],'Value',num2str(Pay));
            set_param([ModelName '/3diem/Pbx_mode4'],'Value',num2str(Pbx)); 
            set_param([ModelName '/3diem/Pby_mode4'],'Value',num2str(Pby)); 
            set_param([ModelName '/3diem/Pcx_mode4'],'Value',num2str(Pcx)); 
            set_param([ModelName '/3diem/Pcy_mode4'],'Value',num2str(Pcy));             
            set_param([ModelName '/3diem/tf_mode4'],'Value',num2str(tf)); 
            set_param([ModelName '/3diem/n_mode4'],'Value',num2str(n));  

            sim(ModelName);
            plot(app.UIAxes_TP, out.x.Data, out.y.Data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroduceTab = uitab(app.TabGroup);
            app.IntroduceTab.Title = 'Introduce';
            app.btn_start = uibutton(app.IntroduceTab, 'push');
            app.btn_start.ButtonPushedFcn = createCallbackFcn(app, @btn_startButtonPushed, true);
            app.btn_start.Text = 'Start';
            app.btn_stop = uibutton(app.IntroduceTab, 'push');
            app.btn_stop.ButtonPushedFcn = createCallbackFcn(app, @btn_stopButtonPushed, true);
            app.btn_stop.Text = 'Stop';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.GridLayout = uigridlayout(app.KinematicsTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Theta1SliderLabel = uilabel(app.GridLayout2);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.GridLayout2);
            app.Theta1Slider.Limits = [-90 90];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Label_FK_T1 = uilabel(app.GridLayout2);
            app.Label_FK_T1.Text = '0.0';
            app.ForwardKinematicsLabel = uilabel(app.GridLayout);
            app.ForwardKinematicsLabel.Text = {'Forward'; 'Kinematics'};
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.Theta2SliderLabel = uilabel(app.GridLayout2_2);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.GridLayout2_2);
            app.Theta2Slider.Limits = [-90 90];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Label_FK_T2 = uilabel(app.GridLayout2_2);
            app.Label_FK_T2.Text = '0.0';
            app.GridLayout2_3 = uigridlayout(app.GridLayout);
            app.Theta3SliderLabel = uilabel(app.GridLayout2_3);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.GridLayout2_3);
            app.Theta3Slider.Limits = [-90 90];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Label_FK_T3 = uilabel(app.GridLayout2_3);
            app.Label_FK_T3.Text = '0.0';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.Label_FK_Px = uilabel(app.GridLayout9);
            app.Label_FK_Px.Text = '0.0';
            app.Label_Px = uilabel(app.GridLayout9);
            app.Label_Px.Text = 'Px =';
            app.GridLayout9_2 = uigridlayout(app.GridLayout4);
            app.Label_FK_Py = uilabel(app.GridLayout9_2);
            app.Label_FK_Py.Text = '0.0';
            app.Label_Py = uilabel(app.GridLayout9_2);
            app.Label_Py.Text = 'Py =';
            app.GridLayout9_3 = uigridlayout(app.GridLayout4);
            app.Label_FK_Pz = uilabel(app.GridLayout9_3);
            app.Label_FK_Pz.Text = '0.0';
            app.Label_Pz = uilabel(app.GridLayout9_3);
            app.Label_Pz.Text = 'Pz =';
            app.InverseKinematicsLabel = uilabel(app.GridLayout);
            app.InverseKinematicsLabel.Text = {'Inverse'; 'Kinematics'};
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PxEditFieldLabel = uilabel(app.GridLayout6);
            app.PxEditFieldLabel.Text = 'Px =';
            app.Px_IK_EditField = uieditfield(app.GridLayout6, 'text');
            app.Px_IK_EditField.ValueChangedFcn = createCallbackFcn(app, @Px_IK_EditFieldValueChanged, true);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5);
            app.PyEditFieldLabel = uilabel(app.GridLayout6_2);
            app.PyEditFieldLabel.Text = 'Py = ';
            app.Py_IK_EditField = uieditfield(app.GridLayout6_2, 'text');
            app.Py_IK_EditField.ValueChangedFcn = createCallbackFcn(app, @Py_IK_EditFieldValueChanged, true);
            app.GridLayout6_3 = uigridlayout(app.GridLayout5);
            app.PzEditFieldLabel = uilabel(app.GridLayout6_3);
            app.PzEditFieldLabel.Text = 'Pz =';
            app.Pz_IK_EditField = uieditfield(app.GridLayout6_3, 'text');
            app.Pz_IK_EditField.ValueChangedFcn = createCallbackFcn(app, @Pz_IK_EditFieldValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = '=>';
            app.GridLayout9_4 = uigridlayout(app.GridLayout7);
            app.Label_IK_T1 = uilabel(app.GridLayout9_4);
            app.Label_IK_T1.Text = '0.0';
            app.Label_T1_2 = uilabel(app.GridLayout9_4);
            app.Label_T1_2.Text = 'T1 =';
            app.GridLayout9_5 = uigridlayout(app.GridLayout7);
            app.Label_IK_T2 = uilabel(app.GridLayout9_5);
            app.Label_IK_T2.Text = '0.0';
            app.Label_T2_2 = uilabel(app.GridLayout9_5);
            app.Label_T2_2.Text = 'T2 =';
            app.GridLayout9_6 = uigridlayout(app.GridLayout7);
            app.Label_IK_T3 = uilabel(app.GridLayout9_6);
            app.Label_IK_T3.Text = '0.0';
            app.Label_T3_2 = uilabel(app.GridLayout9_6);
            app.Label_T3_2.Text = 'T3 =';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.Btn_Check = uibutton(app.GridLayout8, 'push');
            app.Btn_Check.ButtonPushedFcn = createCallbackFcn(app, @Btn_CheckButtonPushed, true);
            app.Btn_Check.Text = 'Check';
            app.Btn_Calculate = uibutton(app.GridLayout8, 'push');
            app.Btn_Calculate.Text = 'Calculate';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.TrajectoryPlanningTab = uitab(app.TabGroup);
            app.TrajectoryPlanningTab.Title = 'TrajectoryPlanning';
            app.GridLayout10 = uigridlayout(app.TrajectoryPlanningTab);
            app.UIAxes_TP = uiaxes(app.GridLayout10);
            title(app.UIAxes_TP, 'Title')
            xlabel(app.UIAxes_TP, 'X')
            ylabel(app.UIAxes_TP, 'Y')
            zlabel(app.UIAxes_TP, 'Z')
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.EditField_PAx = uieditfield(app.GridLayout11, 'text');
            app.EditField_PAy = uieditfield(app.GridLayout11, 'text');
            app.Label_PointA = uilabel(app.GridLayout11);
            app.Label_PointA.Text = 'Point A';
            app.Label_SettingPoint = uilabel(app.GridLayout10);
            app.Label_SettingPoint.Text = 'Setting Point';
            app.GridLayout11_2 = uigridlayout(app.GridLayout10);
            app.EditField_PBx = uieditfield(app.GridLayout11_2, 'text');
            app.EditField_PBy = uieditfield(app.GridLayout11_2, 'text');
            app.Label_PonitB = uilabel(app.GridLayout11_2);
            app.Label_PonitB.Text = 'Ponit B';
            app.GridLayout11_3 = uigridlayout(app.GridLayout10);
            app.EditField_PCx = uieditfield(app.GridLayout11_3, 'text');
            app.EditField_PCy = uieditfield(app.GridLayout11_3, 'text');
            app.Label_PointC = uilabel(app.GridLayout11_3);
            app.Label_PointC.Text = 'Point C';
            app.Label_ModeCheck = uilabel(app.GridLayout10);
            app.Label_ModeCheck.Text = 'Mode Check';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.Btn_3P = uibutton(app.GridLayout12, 'push');
            app.Btn_3P.ButtonPushedFcn = createCallbackFcn(app, @Btn_3PButtonPushed, true);
            app.Btn_3P.Text = '3 Point ABC';
            app.Btn_2P = uibutton(app.GridLayout12, 'push');
            app.Btn_2P.ButtonPushedFcn = createCallbackFcn(app, @Btn_2PButtonPushed, true);
            app.Btn_2P.Text = '2 Point AB';
            app.Btn_ClearResult = uibutton(app.GridLayout10, 'push');
            app.Btn_ClearResult.Text = 'Clear Result';
            app.GridLayout14 = uigridlayout(app.GridLayout10);
            app.GridLayout14_2 = uigridlayout(app.GridLayout14);
            app.tfEditFieldLabel = uilabel(app.GridLayout14_2);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.GridLayout14_2, 'text');
            app.GridLayout14_3 = uigridlayout(app.GridLayout14);
            app.nEditFieldLabel = uilabel(app.GridLayout14_3);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout14_3, 'text');
            app.ObserveTab = uitab(app.TabGroup);
            app.ObserveTab.Title = 'Observe';
            app.GridLayout13 = uigridlayout(app.ObserveTab);
            app.UIAxes = uiaxes(app.GridLayout13);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout13);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout13);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout13);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout13);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout13);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.Btn_PlotX = uibutton(app.GridLayout13, 'push');
            app.Btn_PlotX.Text = 'Plot X';
            app.Btn_PlotY = uibutton(app.GridLayout13, 'push');
            app.Btn_PlotY.Text = 'Plot X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ControlRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end